classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DesignedByAyushGuptaLabel       matlab.ui.control.Label
        SmartTrafficControlSystemLabel  matlab.ui.control.Label
        ResultButton                    matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FileName1 % Description
        FileName2 % Description
        FileName3 % Description
        Count1 % Description
        Count2 % Description
        Count3 % Description
    end
   
    methods (Access = private)
        function VideoPlay(app,file1,file2,file3)
            f=waitbar(0,'Please wait...','Position',[398 180 260 50]);
            pause(.2);
            
            v1=VideoReader(file1);
            v2=VideoReader(file2);
            v3=VideoReader(file3);
            while hasFrame(v1)
                Frame = readFrame(v1);
                imshow(Frame,'Parent',app.UIAxes);
            end
           
            waitbar(0.33,f,'Fetching your data...');
             
            pause(0.2);
            while hasFrame(v2)
                Frame = readFrame(v2);
                imshow(Frame,'Parent',app.UIAxes_2);
            end
           
            waitbar(0.67,f,'Hold your breath..Almost done...');
             
            pause(0.2);
            while hasFrame(v3)
                Frame = readFrame(v3);
                imshow(Frame,'Parent',app.UIAxes_3);
            end
            
           waitbar(1,f,'Finished!');
            
            pause(0.1);
            close(f);
           end
     
        
           function Process(app)
               f=waitbar(0,'Processing...','Position',[398 180 260 50]);
               pause(.2);
           
            the_Video = VideoReader(app.FileName1);

            Object_Detector = vision.ForegroundDetector('NumGaussians',3,'NumTrainingFrames',20,'InitialVariance',10*10);

            for i=1:24
                frame=readFrame(the_Video);
                the_Object=step(Object_Detector,frame);
            end


            Structure = strel('square',1);

            Noise_Free_Object = imopen(the_Object,Structure);
            imshow(Noise_Free_Object,'Parent',app.UIAxes);


            Bounding_Box = vision.BlobAnalysis('BoundingBoxOutputPort', true, ...
                'AreaOutputPort', false, 'CentroidOutputPort', false, ...
                'MinimumBlobArea', 1000);
            the_Box = step(Bounding_Box,Noise_Free_Object);
            Detected_Vehicle = insertShape(frame, 'Rectangle', the_Box,'LineWidth',5,  'Color', 'green');

            Number_of_Cars1 = size(the_Box, 1);
            Detected_Vehicle = insertText(Detected_Vehicle, [50 50], Number_of_Cars1, 'BoxOpacity', 1, 'FontSize', 14);


            videoPlayer = vision.VideoPlayer('Name','Vehicle Detected');
            videoPlayer.Position(3:4) = [650,400];

            while hasFrame(the_Video)
                frame=readFrame(the_Video);
                the_Object=step(Object_Detector,frame);
                Noise_Free_Object = imopen(the_Object,Structure);
                the_Box = step(Bounding_Box,Noise_Free_Object);
                Detected_Vehicle = insertShape(frame, 'Rectangle', the_Box,'LineWidth',5,  'Color', 'green');
                Number_of_Cars1 = size(the_Box, 1);
                Detected_Vehicle = insertText(Detected_Vehicle, [50 50], Number_of_Cars1, 'BoxOpacity', 1, 'FontSize', 25);
                imshow(Detected_Vehicle,'Parent',app.UIAxes);
            end
            app.Count1=Number_of_Cars1;
            waitbar(0.33,f,'Performing Morphological Operations...');
             the_Video = VideoReader(app.FileName2);

            Object_Detector = vision.ForegroundDetector('NumGaussians',3,'NumTrainingFrames',20,'InitialVariance',10*10);

            for i=1:24
                frame=readFrame(the_Video);
                the_Object=step(Object_Detector,frame);
            end


            Structure = strel('square',1);

            Noise_Free_Object = imopen(the_Object,Structure);
            imshow(Noise_Free_Object,'Parent',app.UIAxes_2);


            Bounding_Box = vision.BlobAnalysis('BoundingBoxOutputPort', true, ...
                'AreaOutputPort', false, 'CentroidOutputPort', false, ...
                'MinimumBlobArea', 1000);
            the_Box = step(Bounding_Box,Noise_Free_Object);
            Detected_Vehicle = insertShape(frame, 'Rectangle', the_Box, 'LineWidth',5, 'Color', 'green');

            Number_of_Cars2 = size(the_Box, 1);
            Detected_Vehicle = insertText(Detected_Vehicle, [50 50], Number_of_Cars2, 'BoxOpacity', 1, 'FontSize', 14);


            videoPlayer = vision.VideoPlayer('Name','Vehicle Detected');
            videoPlayer.Position(3:4) = [650,400];

            while hasFrame(the_Video)
                frame=readFrame(the_Video);
                the_Object=step(Object_Detector,frame);
                Noise_Free_Object = imopen(the_Object,Structure);
                the_Box = step(Bounding_Box,Noise_Free_Object);
                Detected_Vehicle = insertShape(frame, 'Rectangle', the_Box, 'LineWidth',5, 'Color', 'green');
                Number_of_Cars2 = size(the_Box, 1);
                Detected_Vehicle = insertText(Detected_Vehicle, [50 50], Number_of_Cars2, 'BoxOpacity', 1, 'FontSize', 25);
                imshow(Detected_Vehicle,'Parent',app.UIAxes_2);
            end
            app.Count2=Number_of_Cars2;
            
             waitbar(0.67,f,'Marking odd one out...');
             the_Video = VideoReader(app.FileName3);

            Object_Detector = vision.ForegroundDetector('NumGaussians',3,'NumTrainingFrames',20,'InitialVariance',10*10);

            for i=1:24
                frame=readFrame(the_Video);
                the_Object=step(Object_Detector,frame);
            end


            Structure = strel('square',1);

            Noise_Free_Object = imopen(the_Object,Structure);
            imshow(Noise_Free_Object,'Parent',app.UIAxes_3);


            Bounding_Box = vision.BlobAnalysis('BoundingBoxOutputPort', true, ...
                'AreaOutputPort', false, 'CentroidOutputPort', false, ...
                'MinimumBlobArea', 1000);
            the_Box = step(Bounding_Box,Noise_Free_Object);
            Detected_Vehicle = insertShape(frame, 'Rectangle', the_Box,'LineWidth',5, 'Color', 'green');

            Number_of_Cars3 = size(the_Box, 1);
            Detected_Vehicle = insertText(Detected_Vehicle, [50 50], Number_of_Cars3, 'BoxOpacity', 1, 'FontSize', 14);


            videoPlayer = vision.VideoPlayer('Name','Vehicle Detected');
            videoPlayer.Position(3:4) = [650,400];

            while hasFrame(the_Video)
                frame=readFrame(the_Video);
                the_Object=step(Object_Detector,frame);
                Noise_Free_Object = imopen(the_Object,Structure);
                the_Box = step(Bounding_Box,Noise_Free_Object);
                Detected_Vehicle = insertShape(frame, 'Rectangle', the_Box,'LineWidth',5, 'Color', 'green');
                Number_of_Cars3 = size(the_Box, 1);
                Detected_Vehicle = insertText(Detected_Vehicle, [50 50], Number_of_Cars3, 'BoxOpacity', 1, 'FontSize', 25);
                imshow(Detected_Vehicle,'Parent',app.UIAxes_3);
            end
            app.Count3=Number_of_Cars3;
            waitbar(1,f,'Finished!');
            
            pause(0.1);
            close(f);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)

            [file1,path1] = uigetfile('*.mp4');
           
            [file2,path2] = uigetfile('*.mp4');
          
            
            [file3,path3] = uigetfile('*.mp4');
           
            app.FileName1=file1;
            app.FileName2=file2;
            app.FileName3=file3;
          
            VideoPlay(app,file1,file2,file3);
            
            
        end

        % Callback function
        function ProcessButtonPushed(app, event)
     
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed2(app, event)

           Process(app);
        end

        % Button pushed function: ResultButton
        function ResultButtonPushed(app, event)
            A=[app.Count1 app.Count2 app.Count3];
            [B,I]=sort(A);
            time=0;
            if((B(3)>=1) &&(B(3)<=5))
               str1="Lane "+ I(3)+ ": Green Light for 5 seconds.";
                time= time +5;
             elseif((B(3)>=5) &&(B(3)<=10))
                 str1="Lane "+ I(3)+ ": Green Light for 10 seconds.";
                 time= time +10;
            else
                str1="Lane "+ I(3)+ ": Green Light for 20 seconds.";
                time= time +20;
            end
            
            if((B(2)>=1) &&(B(2)<=5))
               str2="Then Lane "+ I(2)+ ": Green Light for 5 seconds.";
               time= time +5;
             elseif((B(2)>=5) &&(B(2)<=10))
                 str2="Then Lane "+ I(2)+ ": Green Light for 10 seconds.";
                 time= time +10;
            else
                str2="Then Lane "+ I(2)+ ": Green Light for 20 seconds.";
                time= time + 20;
            end
            
            if((B(1)>=1) &&(B(1)<=5))
               str3="And then Lane "+ I(1)+ ": Green Light for 5 seconds.";
               time= time +5;
             elseif((B(1)>=5) &&(B(1)<=10))
                 str3="And then Lane "+ I(1)+ ": Green Light for 10 seconds.";
                 time= time +10;
            else
                str3="And then Lane "+ I(1)+ ": Green Light for 20 seconds.";
                time= time +5;
            end
            str4="Time elapsed: "+time+"seconds.";
            str5="Again read the frames and perform the operations.";
            f=msgbox({str1,str2,str3,str4,'',str5},'Result');
            set(f,'Position',[398 110 260 120]);
            th=findall(f,'Type','Text');
            set(th,'FontName','Candara','FontSize',11);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'LANE 1')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'LANE 2')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'LANE 3')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed2, true);
            app.ProcessButton.Text = 'Process';
            app.ResultButton = uibutton(app.UIFigure, 'push');
            app.ResultButton.ButtonPushedFcn = createCallbackFcn(app, @ResultButtonPushed, true);
            app.ResultButton.Text = 'Result';
            app.SmartTrafficControlSystemLabel = uilabel(app.UIFigure);
            app.SmartTrafficControlSystemLabel.Text = 'Smart Traffic Control System';
            app.DesignedByAyushGuptaLabel = uilabel(app.UIFigure);
            app.DesignedByAyushGuptaLabel.Text = 'Designed By-Ayush Gupta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end